classdef do_an < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab1                           matlab.ui.container.Tab
        TextArea_4                     matlab.ui.control.TextArea
        TextArea_4Label                matlab.ui.control.Label
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_3Label                matlab.ui.control.Label
        Tab2                           matlab.ui.container.Tab
        Tab2SoLanLapEditField          matlab.ui.control.EditField
        SlnlpEditFieldLabel_2          matlab.ui.control.Label
        Tab2ChonPhuongPhapDropDown     matlab.ui.control.DropDown
        PhngphpLabel                   matlab.ui.control.Label
        Tab2TimNghiemButton            matlab.ui.control.Button
        Tab2KetQuaEditField            matlab.ui.control.EditField
        KtquLabel                      matlab.ui.control.Label
        Tab2NhapSaiSoEditField         matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel          matlab.ui.control.Label
        Tab2KhoangPhanLyEditField      matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        Tab2NhapPhuongTrinhEditField   matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        Tab2UIAxes                     matlab.ui.control.UIAxes
        Tab3                           matlab.ui.container.Tab
        TextArea_6                     matlab.ui.control.TextArea
        TextArea_5                     matlab.ui.control.TextArea
        Tab3PTGocEditField             matlab.ui.control.EditField
        Tab3PhuongPhapDropDown         matlab.ui.control.DropDown
        ChnphngphpLabel                matlab.ui.control.Label
        Tab3VeDoThiButton              matlab.ui.control.Button
        Tab3SoDoanConEditField         matlab.ui.control.NumericEditField
        SonconEditFieldLabel           matlab.ui.control.Label
        Tab3GioiHanDauCuoiEditField    matlab.ui.control.EditField
        GiihnucuiEditFieldLabel        matlab.ui.control.Label
        Tab3NhapY0EditField            matlab.ui.control.NumericEditField
        Nhpy0EditFieldLabel            matlab.ui.control.Label
        Tab3PTViPhanEditField          matlab.ui.control.EditField
        Tab3UIAxes                     matlab.ui.control.UIAxes
        Tab4                           matlab.ui.container.Tab
        Tab4KetQuaTaylorEditField      matlab.ui.control.EditField
        PhngphpTaylorEditFieldLabel_2  matlab.ui.control.Label
        Tab4TDaThucNoiSuyTextArea      matlab.ui.control.TextArea
        athcnisuyLabel                 matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        Tab4HienGiaTriXTextArea        matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        Tab4KetQuaLagrangeEditField    matlab.ui.control.NumericEditField
        PhngphpLagrangeEditFieldLabel  matlab.ui.control.Label
        Tab4TinhKetQuaButton           matlab.ui.control.Button
        Tab4GiaTriXEditField           matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel     matlab.ui.control.Label
        Tab4DuLieuYEditField           matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        Tab4DuLieuXEditField           matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        HiquyTab                       matlab.ui.container.Tab
        Hq_HamHoiQuyTextArea           matlab.ui.control.TextArea
        HmhiquyTextAreaLabel           matlab.ui.control.Label
        Hq_KetQuaDuDoanEditField       matlab.ui.control.EditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        Hq_GiaTriDuDoanEditField       matlab.ui.control.NumericEditField
        GitrcndonEditFieldLabel        matlab.ui.control.Label
        Hq_PhuongPhapDropDown          matlab.ui.control.DropDown
        PhngphphiquyDropDownLabel      matlab.ui.control.Label
        Hq_DuLieuYEditField            matlab.ui.control.EditField
        DliuyEditFieldLabel            matlab.ui.control.Label
        Hq_DuLieuXEditField            matlab.ui.control.EditField
        DliuxEditFieldLabel            matlab.ui.control.Label
        TinhHoiQuyButton               matlab.ui.control.Button
        Hq_UIAxes                      matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        KetQuaEditField                matlab.ui.control.NumericEditField
        KtquEditFieldLabel             matlab.ui.control.Label
        GiaTriXEditField               matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel_2   matlab.ui.control.Label
        PhuongPhapDropDown             matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel     matlab.ui.control.Label
        SaiSoDropDown                  matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel       matlab.ui.control.Label
        BuocHEditField                 matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        HamSoEditField                 matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        DuLieuYEditField               matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2       matlab.ui.control.Label
        DuLieuXEditField               matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2       matlab.ui.control.Label
        TinhDaoHamButton               matlab.ui.control.Button
        HocLabel                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Tab4TinhKetQuaButton
        function Tab4TinhKetQuaButtonPushed(app, event)
            % Lấy dữ liệu từ giao diện người dùng
            % Chuyển đổi chuỗi ký tự từ EditField thành mảng số
            xa = convertStringToNumArray(app.Tab4DuLieuXEditField.Value);
            ya = convertStringToNumArray(app.Tab4DuLieuYEditField.Value);
            x0 = app.Tab4GiaTriXEditField.Value;

            % Hiển thị giá trị x0 trong TextArea phía trên đồ thị
            app.Tab4HienGiaTriXTextArea.Value = ['Giá trị đạo hàm tại x = ' num2str(x0)];
            
            % Tính đa thức nội suy Lagrange theo kiểu dữ liệu syms
            f_Lagrange = LagrangeSymbolic(xa, ya);
            % Hiển thị đa thức nội suy ra giao diện người dùng
            app.Tab4TDaThucNoiSuyTextArea.Value = char(f_Lagrange);
            % Tính giá trị đạo hàm tại x0 của đa thức nội suy
            f_dh = diff(f_Lagrange);
            f_dh_handle = matlabFunction(f_dh);
            result_Lagrange = f_dh_handle(x0);
            % Hiển thị kết quả đạo hàm ra giao diện người dùng
            app.Tab4KetQuaLagrangeEditField.Value = result_Lagrange;

            % Tính giá trị đạo hàm tại x0 bằng phương pháp Taylor theo
            % chiều trung tâm
            result_Taylor = daohamTaylor(xa, ya, x0);
            % Hiển thị kết quả ra giao diện người dùng
            app.Tab4KetQuaTaylorEditField.Value = num2str(result_Taylor);
        end

        % Button pushed function: Tab3VeDoThiButton
        function Tab3VeDoThiButtonPushed(app, event)
            % Lấy các dữ liệu từ giao diện người dùng
            fxy = app.Tab3PTViPhanEditField.Value;
            fxy = str2func(['@(x, y)' fxy]);
            % Lấy giá trị hàm số chính xác (nếu không có là chuỗi rỗng)
            f_original = app.Tab3PTGocEditField.Value;
            N = app.Tab3SoDoanConEditField.Value;
            y0 = app.Tab3NhapY0EditField.Value;
            % Chuyển đổi chuỗi ký tự từ EditField thành mảng số gồm 2 phần
            % tử là 2 giá trị của giới hạn
            gioihan = convertStringToNumArray(app.Tab3GioiHanDauCuoiEditField.Value);
            a = gioihan(1);
            b = gioihan(2);
            method = app.Tab3PhuongPhapDropDown.Value;
            % Dựa vào method để chọn phương pháp giải phương trình vi phân
            if (strcmp(method, 'trungdiem'))
                [x, y] = hienantrungdiem(fxy, a, b, y0, N);
            else
                [x, y] = RK(fxy, a, b, y0, N);
            end

            % Vẽ đồ thị
            cla(app.Tab3UIAxes);
            plot(app.Tab3UIAxes, x, y, 'g','LineWidth', 2);
            hold(app.Tab3UIAxes, 'on');
            % Vẽ đồ thị hàm chính xác (nếu có)
            if ~isempty(f_original)
                f_original = str2func(['@(x)' vectorize(f_original)]);
                y_original = f_original(x);
                % Vẽ đồ thị hàm số gốc 
                plot(app.Tab3UIAxes, x, y_original, 'r--', 'LineWidth', 2);
            end
            % Chú thích cho độ thị
            title(app.Tab3UIAxes, 'Đồ thị hàm số f');
            xlabel(app.Tab3UIAxes, 'Giá trị x');
            ylabel(app.Tab3UIAxes, 'Giá trị y');
            grid(app.Tab3UIAxes, 'on');
            legend(app.Tab3UIAxes, 'Phương trình gần đúng', 'Phương trình chính xác');
        end

        % Button pushed function: Tab2TimNghiemButton
        function Tab2TimNghiemButtonPushed(app, event)
            % Lấy các dữ liệu từ giao diện người dùng
            f = app.Tab2NhapPhuongTrinhEditField.Value;
            f = str2func(['@(x)' vectorize(f)]);
            saiso = app.Tab2NhapSaiSoEditField.Value;
            % Chuyển đổi chuỗi ký tự từ EditField thành mảng số gồm 2 phần
            % tử là 2 giá trị của khoảng phân ly nghiệm
            khoangphanly = convertStringToNumArray(app.Tab2KhoangPhanLyEditField.Value);
            a = khoangphanly(1);
            b = khoangphanly(2);
            method = app.Tab2ChonPhuongPhapDropDown.Value;
            % Dựa trên method để chọn phương pháp tìm nghiệm
            if (strcmp(method, 'chiadoi'))
                [nghiem, solanlap] = chiadoi(f, a, b, saiso);
            else
                [nghiem, solanlap] = daycung(f, a, b, saiso);
            end

            % Hiển thị kết quả ra giao diện người dùng
            app.Tab2KetQuaEditField.Value = num2str(nghiem);
            app.Tab2SoLanLapEditField.Value = num2str(solanlap);

            % Vẽ đồ thị
            x_plt = linspace(a, b, 1000);
            y_plt = f(x_plt);
            cla((app.Tab2UIAxes));
            % Vẽ hàm số x
            plot(app.Tab2UIAxes, x_plt, y_plt, 'g','LineWidth',2);
            hold(app.Tab2UIAxes, 'on');
            % Vẽ trục hoành
            y_zero = zeros(size(x_plt));
            plot(app.Tab2UIAxes, x_plt, y_zero, 'r-', 'LineWidth', 2);
            plot(app.Tab2UIAxes, nghiem, 0, 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b')
            % Chú thích cho đồ thị
            title(app.Tab2UIAxes, 'Đồ thị hàm số f'); 
            xlabel(app.Tab2UIAxes, 'Giá trị x'); 
            ylabel(app.Tab2UIAxes, 'Giá trị y');
            legend(app.Tab2UIAxes, 'Đồ thị hàm số', 'Trục hoành', 'Nghiệm');
            grid(app.Tab2UIAxes, 'on');
        end

        % Button pushed function: TinhDaoHamButton
        function TinhDaoHamButtonPushed(app, event)
            % Lấy các tham số từ giao diện người dùng
            f = app.HamSoEditField.Value;
            if ~isempty(f)
                f = str2func(['@(x)' f]);
            end
            h = app.BuocHEditField.Value;
            % Dùng hàm convertStringToNumArray để chuyển dữ liệu 
            % từ dạng text thành mảng sổ
            x = convertStringToNumArray(app.DuLieuXEditField.Value);
            y =convertStringToNumArray(app.DuLieuYEditField.Value);
            saiso = str2double(app.SaiSoDropDown.Value);
            phuongphap = app.PhuongPhapDropDown.Value;
            x0 = app.GiaTriXEditField.Value;
            % Tính giá trị đạo hàm
            result = tinhdaoham(f, x,y,x0,h,phuongphap,saiso);
            % Hiển thị kết quả
            app.KetQuaEditField.Value = result;
        end

        % Button pushed function: TinhHoiQuyButton
        function TinhHoiQuyButtonPushed(app, event)
            % Lấy dữ liệu từ giao diện người dùng
            % Dùng hàm convertStringToNumArray tự viết để chuyển dữ liệu 
            % từ dạng text thành các mảng sổ
            xa = convertStringToNumArray(app.Hq_DuLieuXEditField.Value);
            ya = convertStringToNumArray(app.Hq_DuLieuYEditField.Value);
            x0 = app.Hq_GiaTriDuDoanEditField.Value;
            method = app.Hq_PhuongPhapDropDown.Value;
            % Dựa trên method để chọn hàm tính toán phù hợp
            if (strcmp(method, 'tuyentinh'))
                [a1, a0, r2] = HoiQuyTuyenTinh(xa, ya);
                f_hq = @(x) a1.*x + a0;
                % Tạo hàm dạng text để hiển thị ra giao diện người dùng
                f_hq_text = sprintf('y = %.4f * x + %.4f', a1, a0);
            elseif (strcmp(method, 'mu'))
                [a, b, r2] = HoiQuyHamMu(xa,ya);
                f_hq = @(x) a .* x.^b;
                % Tạo hàm dạng text để hiển thị ra giao diện người dùng
                f_hq_text = sprintf('y = %.4f * x ^ %.4f', a, b);
            end
            % Hiển thị kết quả ra giao diện người dùng
            app.Hq_HamHoiQuyTextArea.Value = f_hq_text;
            app.Hq_KetQuaDuDoanEditField.Value = num2str(f_hq(x0));
            % Vẽ đồ thị
            x_plt = linspace(min(xa), max(xa), 1000);
            y_plt = f_hq(x_plt);
            
            cla(app.Hq_UIAxes);
            % Vẽ đồ thị hàm hồi quy
            plot(app.Hq_UIAxes, x_plt, y_plt, '-', 'LineWidth', 2);
            hold(app.Hq_UIAxes, 'on');
            % Vẽ dữ liệu thực tế
            plot(app.Hq_UIAxes, xa, ya, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
            hold(app.Hq_UIAxes, 'off')
            % Chú thích cho đồ thị
            title(app.Hq_UIAxes, 'Hồi Quy và Dữ Liệu Thực'); 
            xlabel(app.Hq_UIAxes, 'Giá trị x'); 
            ylabel(app.Hq_UIAxes, 'Giá trị y');
            grid(app.Hq_UIAxes, 'on');
            legend(app.Hq_UIAxes, 'Đường hồi quy', 'Dữ liệu thực');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab 1';
            app.TextArea_3Label = uilabel(app.Tab1);
            app.TextArea_3Label.Text = '';
            app.TextArea_3 = uitextarea(app.Tab1);
            app.TextArea_3.Value = {''; ''; '                Thành viên:                                                                   Nhiệm vụ'; '     22200107 - Phạm Thị Ánh Ngân                       Tab 2, làm báo cáo, kiểm tra app'; '     22200108 - Mai Hữu Ngạn                                 Tab 4, tích phân, tổng hợp app'; '     22200119 - Nguyễn Ngọc Quỳnh Như              Tab 2, làm báo cáo, kiểm tra app'; '     22200135 - Phạm Phương Quỳnh                     Tab 3, vẽ flowchart, làm báo cáo'};
            app.TextArea_4Label = uilabel(app.Tab1);
            app.TextArea_4Label.Text = '';
            app.TextArea_4 = uitextarea(app.Tab1);
            app.TextArea_4.Value = {'    GIỚI THIỆU NHÓM'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab 2';
            app.Tab2UIAxes = uiaxes(app.Tab2);
            title(app.Tab2UIAxes, 'Đồ thị hàm số y = f(x)')
            xlabel(app.Tab2UIAxes, 'X')
            ylabel(app.Tab2UIAxes, 'Y')
            zlabel(app.Tab2UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.Tab2);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Tab2NhapPhuongTrinhEditField = uieditfield(app.Tab2, 'text');
            app.Tab2NhapPhuongTrinhEditField.Value = 'x^2 -4';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.Tab2);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.Tab2KhoangPhanLyEditField = uieditfield(app.Tab2, 'text');
            app.Tab2KhoangPhanLyEditField.Value = '1.5, 2.5';
            app.NhpsaisEditFieldLabel = uilabel(app.Tab2);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số';
            app.Tab2NhapSaiSoEditField = uieditfield(app.Tab2, 'numeric');
            app.Tab2NhapSaiSoEditField.Value = 0.001;
            app.KtquLabel = uilabel(app.Tab2);
            app.KtquLabel.Text = 'Kết quả nghiệm';
            app.Tab2KetQuaEditField = uieditfield(app.Tab2, 'text');
            app.Tab2TimNghiemButton = uibutton(app.Tab2, 'push');
            app.Tab2TimNghiemButton.ButtonPushedFcn = createCallbackFcn(app, @Tab2TimNghiemButtonPushed, true);
            app.Tab2TimNghiemButton.Text = 'Tính toán';
            app.PhngphpLabel = uilabel(app.Tab2);
            app.PhngphpLabel.Text = 'Phương pháp';
            app.Tab2ChonPhuongPhapDropDown = uidropdown(app.Tab2);
            app.Tab2ChonPhuongPhapDropDown.Items = {'Chia đôi', 'Dây cung'};
            app.Tab2ChonPhuongPhapDropDown.ItemsData = {'chiadoi', 'daycung'};
            app.Tab2ChonPhuongPhapDropDown.Value = 'chiadoi';
            app.SlnlpEditFieldLabel_2 = uilabel(app.Tab2);
            app.SlnlpEditFieldLabel_2.Text = 'Số lần lặp';
            app.Tab2SoLanLapEditField = uieditfield(app.Tab2, 'text');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab 3';
            app.Tab3UIAxes = uiaxes(app.Tab3);
            title(app.Tab3UIAxes, 'Đồ thị hàm y = f(x)')
            xlabel(app.Tab3UIAxes, 'X')
            ylabel(app.Tab3UIAxes, 'Y')
            zlabel(app.Tab3UIAxes, 'Z')
            app.Tab3PTViPhanEditField = uieditfield(app.Tab3, 'text');
            app.Tab3PTViPhanEditField.Value = '(1-x) * y';
            app.Nhpy0EditFieldLabel = uilabel(app.Tab3);
            app.Nhpy0EditFieldLabel.Text = 'Nhập y0';
            app.Tab3NhapY0EditField = uieditfield(app.Tab3, 'numeric');
            app.Tab3NhapY0EditField.Value = 1;
            app.GiihnucuiEditFieldLabel = uilabel(app.Tab3);
            app.GiihnucuiEditFieldLabel.Text = 'Giới hạn đầu cuối';
            app.Tab3GioiHanDauCuoiEditField = uieditfield(app.Tab3, 'text');
            app.Tab3GioiHanDauCuoiEditField.Value = '0, 5';
            app.SonconEditFieldLabel = uilabel(app.Tab3);
            app.SonconEditFieldLabel.Text = 'Số đoạn con';
            app.Tab3SoDoanConEditField = uieditfield(app.Tab3, 'numeric');
            app.Tab3SoDoanConEditField.Value = 25;
            app.Tab3VeDoThiButton = uibutton(app.Tab3, 'push');
            app.Tab3VeDoThiButton.ButtonPushedFcn = createCallbackFcn(app, @Tab3VeDoThiButtonPushed, true);
            app.Tab3VeDoThiButton.Text = 'Vẽ đồ thị';
            app.ChnphngphpLabel = uilabel(app.Tab3);
            app.ChnphngphpLabel.Text = 'Chọn phương pháp';
            app.Tab3PhuongPhapDropDown = uidropdown(app.Tab3);
            app.Tab3PhuongPhapDropDown.Items = {'Hiện ẩn trung điểm', 'Runge Kutta'};
            app.Tab3PhuongPhapDropDown.ItemsData = {'trungdiem', 'rk'};
            app.Tab3PhuongPhapDropDown.Value = 'trungdiem';
            app.Tab3PTGocEditField = uieditfield(app.Tab3, 'text');
            app.Tab3PTGocEditField.Value = 'exp(x - x^2/2)';
            app.TextArea_5 = uitextarea(app.Tab3);
            app.TextArea_5.Value = {'Nhập phương trình gốc'; '(nếu có)'};
            app.TextArea_6 = uitextarea(app.Tab3);
            app.TextArea_6.Value = {'Nhập phương trình vi phân'};
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.NhpdliuxEditFieldLabel = uilabel(app.Tab4);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.Tab4DuLieuXEditField = uieditfield(app.Tab4, 'text');
            app.Tab4DuLieuXEditField.Value = '0.1 0.3 0.5 0.7 0.9';
            app.NhpdliuyEditFieldLabel = uilabel(app.Tab4);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Tab4DuLieuYEditField = uieditfield(app.Tab4, 'text');
            app.Tab4DuLieuYEditField.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.Tab4);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.Tab4GiaTriXEditField = uieditfield(app.Tab4, 'numeric');
            app.Tab4GiaTriXEditField.Value = 0.14;
            app.Tab4TinhKetQuaButton = uibutton(app.Tab4, 'push');
            app.Tab4TinhKetQuaButton.ButtonPushedFcn = createCallbackFcn(app, @Tab4TinhKetQuaButtonPushed, true);
            app.Tab4TinhKetQuaButton.Text = 'Tính Đạo hàm';
            app.PhngphpLagrangeEditFieldLabel = uilabel(app.Tab4);
            app.PhngphpLagrangeEditFieldLabel.Text = 'Phương pháp Lagrange';
            app.Tab4KetQuaLagrangeEditField = uieditfield(app.Tab4, 'numeric');
            app.TextAreaLabel = uilabel(app.Tab4);
            app.TextAreaLabel.Text = '';
            app.Tab4HienGiaTriXTextArea = uitextarea(app.Tab4);
            app.Tab4HienGiaTriXTextArea.Value = {'Giá trị đạo hàm tại x = '};
            app.TextArea_2 = uitextarea(app.Tab4);
            app.TextArea_2.Value = {'Nhập dữ liệu'};
            app.athcnisuyLabel = uilabel(app.Tab4);
            app.athcnisuyLabel.Text = 'Đa thức nội suy';
            app.Tab4TDaThucNoiSuyTextArea = uitextarea(app.Tab4);
            app.PhngphpTaylorEditFieldLabel_2 = uilabel(app.Tab4);
            app.PhngphpTaylorEditFieldLabel_2.Text = 'Phương pháp Taylor';
            app.Tab4KetQuaTaylorEditField = uieditfield(app.Tab4, 'text');
            app.Tab4KetQuaTaylorEditField.Value = '0';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.Hq_UIAxes = uiaxes(app.HiquyTab);
            title(app.Hq_UIAxes, 'Đồ thị')
            xlabel(app.Hq_UIAxes, 'X')
            ylabel(app.Hq_UIAxes, 'Y')
            zlabel(app.Hq_UIAxes, 'Z')
            app.TinhHoiQuyButton = uibutton(app.HiquyTab, 'push');
            app.TinhHoiQuyButton.ButtonPushedFcn = createCallbackFcn(app, @TinhHoiQuyButtonPushed, true);
            app.TinhHoiQuyButton.Text = 'Tính toán';
            app.DliuxEditFieldLabel = uilabel(app.HiquyTab);
            app.DliuxEditFieldLabel.Text = 'Dữ liệu x';
            app.Hq_DuLieuXEditField = uieditfield(app.HiquyTab, 'text');
            app.Hq_DuLieuXEditField.Value = '6 7 11 15 17 21 23 29 29 37 39';
            app.DliuyEditFieldLabel = uilabel(app.HiquyTab);
            app.DliuyEditFieldLabel.Text = 'Dữ liệu y';
            app.Hq_DuLieuYEditField = uieditfield(app.HiquyTab, 'text');
            app.Hq_DuLieuYEditField.Value = '29 21 29 14 21 15 7 7 13 0 3';
            app.PhngphphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.PhngphphiquyDropDownLabel.Text = 'Phương pháp hồi quy';
            app.Hq_PhuongPhapDropDown = uidropdown(app.HiquyTab);
            app.Hq_PhuongPhapDropDown.Items = {'Tuyến tính', 'Hàm mũ'};
            app.Hq_PhuongPhapDropDown.ItemsData = {'tuyentinh', 'mu'};
            app.Hq_PhuongPhapDropDown.Value = 'tuyentinh';
            app.GitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.GitrcndonEditFieldLabel.Text = 'Giá trị cần dự đoán';
            app.Hq_GiaTriDuDoanEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.Hq_KetQuaDuDoanEditField = uieditfield(app.HiquyTab, 'text');
            app.HmhiquyTextAreaLabel = uilabel(app.HiquyTab);
            app.HmhiquyTextAreaLabel.Text = 'Hàm hồi quy';
            app.Hq_HamHoiQuyTextArea = uitextarea(app.HiquyTab);
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.TinhDaoHamButton = uibutton(app.ohmTab, 'push');
            app.TinhDaoHamButton.ButtonPushedFcn = createCallbackFcn(app, @TinhDaoHamButtonPushed, true);
            app.TinhDaoHamButton.Text = 'Tính toán';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.ohmTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.DuLieuXEditField = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.ohmTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.DuLieuYEditField = uieditfield(app.ohmTab, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.HamSoEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.BuocHEditField = uieditfield(app.ohmTab, 'numeric');
            app.BuocHEditField.Value = 0.1;
            app.ChngitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.SaiSoDropDown = uidropdown(app.ohmTab);
            app.SaiSoDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaiSoDropDown.ItemsData = {'1', '2'};
            app.SaiSoDropDown.Value = '1';
            app.ChnphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.PhuongPhapDropDown = uidropdown(app.ohmTab);
            app.PhuongPhapDropDown.Items = {'Xấp xỉ Tiến', 'Xấp xỉ Lùi', 'Xấp xỉ Trung tâm'};
            app.PhuongPhapDropDown.ItemsData = {'tien', 'lui', 'trungtam'};
            app.PhuongPhapDropDown.Value = 'tien';
            app.GitrcntnhohmEditFieldLabel_2 = uilabel(app.ohmTab);
            app.GitrcntnhohmEditFieldLabel_2.Text = 'Giá trị cần tính đạo hàm';
            app.GiaTriXEditField = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQuaEditField = uieditfield(app.ohmTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = do_an

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end